classdef Flight_Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ModelTab                       matlab.ui.container.Tab
        WindModelSwitchLabel           matlab.ui.control.Label
        WindModelSwitch                matlab.ui.control.Switch
        RUNButton                      matlab.ui.control.Button
        SpinningRocketSwitchLabel      matlab.ui.control.Label
        SpinningRocketSwitch           matlab.ui.control.Switch
        FincantangleEditFieldLabel     matlab.ui.control.Label
        FincantangleEditField          matlab.ui.control.NumericEditField
        degLabel_3                     matlab.ui.control.Label
        STOPButton                     matlab.ui.control.Button
        ImportParametersButton         matlab.ui.control.Button
        AsymmetriesTab                 matlab.ui.container.Tab
        ThrustMisalignmentSwitchLabel  matlab.ui.control.Label
        ThrustMisalignmentSwitch       matlab.ui.control.Switch
        CGoffsetSwitchLabel            matlab.ui.control.Label
        CGoffsetSwitch                 matlab.ui.control.Switch
        AngleinPitchplaneEditFieldLabel  matlab.ui.control.Label
        AngleinPitchplaneEditField     matlab.ui.control.NumericEditField
        AngleinYawplaneEditFieldLabel  matlab.ui.control.Label
        AngleinYawplaneEditField       matlab.ui.control.NumericEditField
        OffsetinZaxisEditFieldLabel    matlab.ui.control.Label
        OffsetinZaxisEditField         matlab.ui.control.NumericEditField
        OffsetinYaxisEditField_2Label  matlab.ui.control.Label
        OffsetinYaxisEditField_2       matlab.ui.control.NumericEditField
        degLabel                       matlab.ui.control.Label
        degLabel_2                     matlab.ui.control.Label
        mLabel_2                       matlab.ui.control.Label
        mLabel                         matlab.ui.control.Label
        mLabel_3                       matlab.ui.control.Label
        mLabel_4                       matlab.ui.control.Label
        OffsetinZaxisEditField_2Label  matlab.ui.control.Label
        OffsetinZaxisEditField_2       matlab.ui.control.NumericEditField
        OffsetinYaxisEditField_3Label  matlab.ui.control.Label
        OffsetinYaxisEditField_3       matlab.ui.control.NumericEditField
        WindModelTab                   matlab.ui.container.Tab
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.AngleinPitchplaneEditFieldLabel.Visible = 'off';
             app.AngleinYawplaneEditFieldLabel.Visible = 'off';
             app.OffsetinZaxisEditFieldLabel.Visible='off';
             app.OffsetinYaxisEditField_2Label.Visible='off';
             app.OffsetinZaxisEditField_2Label.Visible='off';
             app.OffsetinYaxisEditField_3Label.Visible='off';
             app.FincantangleEditFieldLabel.Visible='off';
        end

        % Value changed function: ThrustMisalignmentSwitch
        function ThrustMisalignmentSwitchValueChanged(app, event)
            value = app.ThrustMisalignmentSwitch.Value;
            switch value
                case 'On'
                    app.AngleinPitchplaneEditField.Visible ='on';
                    app.AngleinPitchplaneEditFieldLabel.Visible = 'on';
                    app.degLabel.Visible = 'On';
                    app.AngleinYawplaneEditField.Visible ='on';
                    app.AngleinYawplaneEditFieldLabel.Visible = 'on';
                    app.degLabel_2.Visible = 'On';
                    app.OffsetinZaxisEditField.Visible='on';
                    app.OffsetinYaxisEditField_2.Visible='on';
                    app.OffsetinZaxisEditFieldLabel.Visible='on';
                    app.OffsetinYaxisEditField_2Label.Visible='on';
                    app.mLabel.Visible='on';
                    app.mLabel_2.Visible='on';
                case 'Off'
                    app.AngleinPitchplaneEditField.Visible ='off';
                    app.AngleinPitchplaneEditFieldLabel.Visible = 'off';
                    app.degLabel.Visible = 'Off';
                    app.AngleinYawplaneEditField.Visible ='off';
                    app.AngleinYawplaneEditFieldLabel.Visible = 'off';
                    app.degLabel_2.Visible = 'Off';
                    app.OffsetinZaxisEditField.Visible='off';
                    app.OffsetinYaxisEditField_2.Visible='off';
                    app.OffsetinZaxisEditFieldLabel.Visible='off';
                    app.OffsetinYaxisEditField_2Label.Visible='off';
                    app.mLabel.Visible='off';
                    app.mLabel_2.Visible='off';                    
            end            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            sim('Test_conditions.slx')
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            set_param('Test_conditions','SimulationCommand','Stop')
        end

        % Value changed function: AngleinPitchplaneEditField
        function AngleinPitchplaneEditFieldValueChanged(app, event)
            value = app.AngleinPitchplaneEditField.Value;
            thrustangle_pitch=value;
        end

        % Value changed function: SpinningRocketSwitch
        function SpinningRocketSwitchValueChanged(app, event)
            value = app.SpinningRocketSwitch.Value;
            switch value
                case 'On'
                    app.FincantangleEditField.Visible='On';
                    app.FincantangleEditFieldLabel.Visible='On';
                    app.degLabel_3.Visible = 'On';
                case 'Off'
                    app.FincantangleEditField.Visible='Off';
                    app.FincantangleEditFieldLabel.Visible='off';
                    app.degLabel_3.Visible = 'Off';
            end
        end

        % Button pushed function: ImportParametersButton
        function ImportParametersButtonPushed(app, event)
            run('RocketParametersImport.m')
        end

        % Value changed function: CGoffsetSwitch
        function CGoffsetSwitchValueChanged(app, event)
            value = app.CGoffsetSwitch.Value;
            switch value
                case 'On'
                    app.OffsetinZaxisEditField_2.Visible='On';
                    app.OffsetinZaxisEditField_2Label.Visible='on';
                    app.OffsetinYaxisEditField_3.Visible='On';
                    app.OffsetinYaxisEditField_3Label.Visible='on';
                    app.mLabel_3.Visible = 'On';
                    app.mLabel_4.Visible='on';
                case 'Off'
                    app.OffsetinZaxisEditField_2.Visible='Off';
                    app.OffsetinZaxisEditField_2Label.Visible='off';
                    app.OffsetinYaxisEditField_3.Visible='Off';
                    app.OffsetinYaxisEditField_3Label.Visible='off';
                    app.mLabel_3.Visible = 'Off';
                    app.mLabel_4.Visible='off';

            end            
        end

        % Value changed function: WindModelSwitch
        function WindModelSwitchValueChanged(app, event)
            value = app.WindModelSwitch.Value;
            switch value
                case 'On'
                    Wind_model=1;
                case 'Off'
                    Wind_model=0;
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.WindModelSwitchLabel = uilabel(app.ModelTab);
            app.WindModelSwitchLabel.Text = 'Wind Model';
            app.WindModelSwitch = uiswitch(app.ModelTab, 'slider');
            app.WindModelSwitch.ValueChangedFcn = createCallbackFcn(app, @WindModelSwitchValueChanged, true);
            app.RUNButton = uibutton(app.ModelTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.SpinningRocketSwitchLabel = uilabel(app.ModelTab);
            app.SpinningRocketSwitchLabel.Text = 'Spinning Rocket';
            app.SpinningRocketSwitch = uiswitch(app.ModelTab, 'slider');
            app.SpinningRocketSwitch.ValueChangedFcn = createCallbackFcn(app, @SpinningRocketSwitchValueChanged, true);
            app.FincantangleEditFieldLabel = uilabel(app.ModelTab);
            app.FincantangleEditFieldLabel.Text = 'Fin cant angle';
            app.FincantangleEditField = uieditfield(app.ModelTab, 'numeric');
            app.FincantangleEditField.Visible = 'off';
            app.degLabel_3 = uilabel(app.ModelTab);
            app.degLabel_3.Visible = 'off';
            app.degLabel_3.Text = 'deg';
            app.STOPButton = uibutton(app.ModelTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.ImportParametersButton = uibutton(app.ModelTab, 'push');
            app.ImportParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ImportParametersButtonPushed, true);
            app.ImportParametersButton.Text = 'Import Parameters';
            app.AsymmetriesTab = uitab(app.TabGroup);
            app.AsymmetriesTab.Title = 'Asymmetries';
            app.ThrustMisalignmentSwitchLabel = uilabel(app.AsymmetriesTab);
            app.ThrustMisalignmentSwitchLabel.Text = 'Thrust Misalignment';
            app.ThrustMisalignmentSwitch = uiswitch(app.AsymmetriesTab, 'slider');
            app.ThrustMisalignmentSwitch.ValueChangedFcn = createCallbackFcn(app, @ThrustMisalignmentSwitchValueChanged, true);
            app.CGoffsetSwitchLabel = uilabel(app.AsymmetriesTab);
            app.CGoffsetSwitchLabel.Text = 'CG offset';
            app.CGoffsetSwitch = uiswitch(app.AsymmetriesTab, 'slider');
            app.CGoffsetSwitch.ValueChangedFcn = createCallbackFcn(app, @CGoffsetSwitchValueChanged, true);
            app.AngleinPitchplaneEditFieldLabel = uilabel(app.AsymmetriesTab);
            app.AngleinPitchplaneEditFieldLabel.Text = 'Angle in Pitch-plane';
            app.AngleinPitchplaneEditField = uieditfield(app.AsymmetriesTab, 'numeric');
            app.AngleinPitchplaneEditField.ValueChangedFcn = createCallbackFcn(app, @AngleinPitchplaneEditFieldValueChanged, true);
            app.AngleinPitchplaneEditField.Visible = 'off';
            app.AngleinYawplaneEditFieldLabel = uilabel(app.AsymmetriesTab);
            app.AngleinYawplaneEditFieldLabel.Text = 'Angle in Yaw-plane';
            app.AngleinYawplaneEditField = uieditfield(app.AsymmetriesTab, 'numeric');
            app.AngleinYawplaneEditField.Visible = 'off';
            app.OffsetinZaxisEditFieldLabel = uilabel(app.AsymmetriesTab);
            app.OffsetinZaxisEditFieldLabel.Text = 'Offset in Z-axis';
            app.OffsetinZaxisEditField = uieditfield(app.AsymmetriesTab, 'numeric');
            app.OffsetinZaxisEditField.Visible = 'off';
            app.OffsetinYaxisEditField_2Label = uilabel(app.AsymmetriesTab);
            app.OffsetinYaxisEditField_2Label.Text = 'Offset in Y-axis';
            app.OffsetinYaxisEditField_2 = uieditfield(app.AsymmetriesTab, 'numeric');
            app.OffsetinYaxisEditField_2.Visible = 'off';
            app.degLabel = uilabel(app.AsymmetriesTab);
            app.degLabel.Visible = 'off';
            app.degLabel.Text = 'deg';
            app.degLabel_2 = uilabel(app.AsymmetriesTab);
            app.degLabel_2.Visible = 'off';
            app.degLabel_2.Text = 'deg';
            app.mLabel_2 = uilabel(app.AsymmetriesTab);
            app.mLabel_2.Visible = 'off';
            app.mLabel_2.Text = 'm';
            app.mLabel = uilabel(app.AsymmetriesTab);
            app.mLabel.Visible = 'off';
            app.mLabel.Text = 'm';
            app.mLabel_3 = uilabel(app.AsymmetriesTab);
            app.mLabel_3.Visible = 'off';
            app.mLabel_3.Text = 'm';
            app.mLabel_4 = uilabel(app.AsymmetriesTab);
            app.mLabel_4.Visible = 'off';
            app.mLabel_4.Text = 'm';
            app.OffsetinZaxisEditField_2Label = uilabel(app.AsymmetriesTab);
            app.OffsetinZaxisEditField_2Label.Text = 'Offset in Z-axis';
            app.OffsetinZaxisEditField_2 = uieditfield(app.AsymmetriesTab, 'numeric');
            app.OffsetinZaxisEditField_2.Visible = 'off';
            app.OffsetinYaxisEditField_3Label = uilabel(app.AsymmetriesTab);
            app.OffsetinYaxisEditField_3Label.Text = 'Offset in Y-axis';
            app.OffsetinYaxisEditField_3 = uieditfield(app.AsymmetriesTab, 'numeric');
            app.OffsetinYaxisEditField_3.Visible = 'off';
            app.WindModelTab = uitab(app.TabGroup);
            app.WindModelTab.Title = 'Wind Model';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Flight_Simulator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end